classdef pokerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PokerGameUIFigure    matlab.ui.Figure
        AIHandPanel          matlab.ui.container.Panel
        Image_2              matlab.ui.control.Image
        Image_11             matlab.ui.control.Image
        Label                matlab.ui.control.Label
        Label_2              matlab.ui.control.Label
        TablePanel           matlab.ui.container.Panel
        Image_10             matlab.ui.control.Image
        Image_12             matlab.ui.control.Image
        Image_13             matlab.ui.control.Image
        Image_14             matlab.ui.control.Image
        Image_15             matlab.ui.control.Image
        Label_3              matlab.ui.control.Label
        Label_4              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        Label_6              matlab.ui.control.Label
        Label_7              matlab.ui.control.Label
        YourHandPanel        matlab.ui.container.Panel
        Image_9              matlab.ui.control.Image
        Image_16             matlab.ui.control.Image
        Label_8              matlab.ui.control.Label
        Label_9              matlab.ui.control.Label
        StartGameButton      matlab.ui.control.Button
        AddtoAIHandButton    matlab.ui.control.Button
        AddtoTableButton     matlab.ui.control.Button
        AddtoYourHandButton  matlab.ui.control.Button
        SeewhowonButton      matlab.ui.control.Button
        WinnerLabel          matlab.ui.control.Label
        NewHandButton        matlab.ui.control.Button
        YourBestHandLabel    matlab.ui.control.Label
        AIsBestHandLabel     matlab.ui.control.Label
        AIsbankLabel         matlab.ui.control.Label
        YourbankLabel        matlab.ui.control.Label
        YourbetLabel         matlab.ui.control.Label
        AIsbetLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        hand = {'00','00'};
        cards = {'00' '00' '00' '00' '00' '00' '00'};
        table = {'00' '00' '00' '00' '00'};
        oppHand = {'00' '00'};
        
        nHand = 0;
        nOppHand = 0;
        nTable = 0;
        
        aiinitialMoney = 20000;
        oppinitialMoney = 20000;
        netTransfer=load('identifyCards.mat');
        cam = webcam;
        
        aibet = 0;
        oppbet = 0;
        
        handimageArr = {0 0}
        tableimageArr = {0 0 0 0 0}
        oppimageArr = {0 0}
        
        handlabelArr = {0 0};
        tablelabelArr = {0 0 0 0 0}
        opplabelArr = {0 0};
        
        cardback = imread('greenback.jpg');
        allimgs = [];
        alllabels = [];
        ai_bestHand = '';
        opp_bestHand = '';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.hand = {'00','00'};
            app.cards = {'00' '00' '00' '00' '00' '00' '00'};
            app.table = {'00' '00' '00' '00' '00'};
            app.oppHand = {'00' '00'};
            app.nHand = 0;
            app.nOppHand = 0;
            app.nTable = 0;
            app.aiinitialMoney = 20000;
            app.oppinitialMoney = 20000;
            app.netTransfer=load('identifyCards.mat');
            app.WinnerLabel.Text = 'Winner: ';
            
            app.handimageArr = {app.Image_11 app.Image_2};
            app.tableimageArr = {app.Image_12 app.Image_13 app.Image_10 app.Image_14 app.Image_15 };
            app.oppimageArr = {app.Image_16 app.Image_9};
            
            app.handlabelArr = {app.Label app.Label_2};
            app.tablelabelArr = {app.Label_3 app.Label_4 app.Label_5 app.Label_6 app.Label_7};
            app.opplabelArr = {app.Label_8 app.Label_9};
            
            app.AIsbankLabel.Text = ['AI''s Bank: ' num2str(app.aiinitialMoney)];
            app.YourbankLabel.Text = ['Your Bank: ' num2str(app.oppinitialMoney)];
            
            app.AIsBestHandLabel.Text = 'AI''s Best Hand: ';
            app.YourBestHandLabel.Text = 'Your Best Hand: ';
            app.AIsbetLabel.Text = 'AI''s bet: ';
            app.YourbetLabel.Text = 'Your bet: ';
            preview(app.cam);
            app.allimgs = [app.handimageArr app.tableimageArr app.oppimageArr];
            app.alllabels = [app.handlabelArr app.tablelabelArr app.opplabelArr];
            for i = 1:length(app.allimgs)
                app.allimgs{i}.ImageSource = app.cardback;
            end
            for i = 1:length(app.alllabels)
                app.alllabels{i}.Visible = 'off';
            end
        end

        % Button pushed function: AddtoAIHandButton
        function AddtoAIHandButtonPushed(app, event)

            im = snapshot(app.cam);
            c = imresize(im,[227,227]);
            out = app.netTransfer.netTransfer.classify(c);
            if app.nHand < 2
                app.nHand = app.nHand + 1;
                app.hand{app.nHand} = char(out);
                [app.aibet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                [app.oppbet, app.opp_bestHand] = build_hands_for_app([app.oppHand(1:app.nOppHand) app.table(1:app.nTable)],app.nOppHand + app.nTable);
                app.handimageArr{app.nHand}.ImageSource = im;
                app.handlabelArr{app.nHand}.Text = char(out);
                app.handlabelArr{app.nHand}.Visible = 'on';
                app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
            end
        end

        % Button pushed function: AddtoTableButton
        function AddtoTableButtonPushed(app, event)
            im = snapshot(app.cam);
            c = imresize(im,[227,227]);
            out = app.netTransfer.netTransfer.classify(c);
            if app.nTable < 5
                app.nTable = app.nTable + 1;
                app.table{app.nTable} = char(out);
                [app.aibet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                [app.oppbet, app.opp_bestHand] = build_hands_for_app([app.oppHand(1:app.nOppHand) app.table(1:app.nTable)],app.nOppHand + app.nTable);
                app.tableimageArr{app.nTable}.ImageSource = im;
                app.tablelabelArr{app.nTable}.Text = char(out);
                app.tablelabelArr{app.nTable}.Visible = 'on';
                app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
            end
        end

        % Button pushed function: AddtoYourHandButton
        function AddtoYourHandButtonPushed(app, event)
            im = snapshot(app.cam);
            c = imresize(im,[227,227]);
            out = app.netTransfer.netTransfer.classify(c);
            if app.nOppHand < 2
                app.nOppHand = app.nOppHand + 1;
                app.oppHand{app.nOppHand} = char(out);
                [app.aibet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                [app.oppbet, app.opp_bestHand] = build_hands_for_app([app.oppHand(1:app.nOppHand) app.table(1:app.nTable)],app.nOppHand + app.nTable);
                app.oppimageArr{app.nOppHand}.ImageSource = im;
                app.opplabelArr{app.nOppHand}.Text = char(out);
                app.opplabelArr{app.nOppHand}.Visible = 'on';
                app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
            end
        end

        % Button pushed function: SeewhowonButton
        function SeewhowonButtonPushed(app, event)
            [app.oppbet, app.opp_bestHand] = build_hands_for_app([app.oppHand app.table],app.nOppHand + app.nTable);
            [app.aibet, app.ai_bestHand] = build_hands_for_app([app.hand app.table],app.nHand + app.nTable);
            if app.oppbet > app.aibet
                app.WinnerLabel.Text = [app.WinnerLabel.Text 'You Won!'];
                app.aiinitialMoney = app.aiinitialMoney - app.oppbet;
                app.oppinitialMoney = app.oppinitialMoney + app.oppbet;
                app.nHand = 0;
                app.nTable = 0;
                app.nOppHand = 0;
            elseif app.aibet == app.oppbet
                app.WinnerLabel.Text = [app.WinnerLabel.Text 'It''s a tie'];
                app.nHand = 0;
                app.nTable = 0;
                app.nOppHand = 0;
            else
                app.WinnerLabel.Text = [app.WinnerLabel.Text 'You lost'];
                app.nHand = 0;
                app.nTable = 0;
                app.nOppHand = 0;
                app.aiinitialMoney = app.aiinitialMoney + app.aibet;
                app.oppinitialMoney = app.oppinitialMoney - app.aibet;
            end
            
            if app.aiinitialMoney < 0
                app.WinnerLabel.Text = [app.WinnerLabel.Text 'I''m Bankrupt! Wanna play again?'];
                app.nHand = 0;
                app.nTable = 0;
                app.nOppHand = 0;
            end
            app.WinnerLabel.Visible = 'on';
            app.AIsbankLabel.Text = ['AI''s Bank: ' num2str(app.aiinitialMoney)];
            app.YourbankLabel.Text = ['Your Bank: ' num2str(app.oppinitialMoney)];
        end

        % Button pushed function: NewHandButton
        function NewHandButtonPushed(app, event)
                app.WinnerLabel.Text = 'Winner: ';
                app.nHand = 0;
                app.nTable = 0;
                app.nOppHand = 0;
                app.hand = {'00','00'};
                app.cards = {'00' '00' '00' '00' '00' '00' '00'};
                app.table = {'00' '00' '00' '00' '00'};
                app.oppHand = {'00' '00'};
                app.AIsBestHandLabel.Text = 'AI''s Best Hand: ';
                app.YourBestHandLabel.Text = 'Your Best Hand: ';
                app.AIsbetLabel.Text = 'AI''s bet: ';
                app.YourbetLabel.Text = 'Your bet: ';
                for i = 1:length(app.allimgs)
                    app.allimgs{i}.ImageSource = app.cardback;
                end
                for i = 1:length(app.alllabels)
                    app.alllabels{i}.Visible = 'off';
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PokerGameUIFigure = uifigure('Visible', 'off');
            app.PokerGameUIFigure.Name = 'Poker Game';
            app.AIHandPanel = uipanel(app.PokerGameUIFigure);
            app.AIHandPanel.Title = 'AI Hand';
            app.Image_2 = uiimage(app.AIHandPanel);
            app.Image_11 = uiimage(app.AIHandPanel);
            app.Label = uilabel(app.AIHandPanel);
            app.Label.Visible = 'off';
            app.Label_2 = uilabel(app.AIHandPanel);
            app.Label_2.Visible = 'off';
            app.TablePanel = uipanel(app.PokerGameUIFigure);
            app.TablePanel.Title = 'Table';
            app.Image_10 = uiimage(app.TablePanel);
            app.Image_12 = uiimage(app.TablePanel);
            app.Image_13 = uiimage(app.TablePanel);
            app.Image_14 = uiimage(app.TablePanel);
            app.Image_15 = uiimage(app.TablePanel);
            app.Label_3 = uilabel(app.TablePanel);
            app.Label_3.Visible = 'off';
            app.Label_4 = uilabel(app.TablePanel);
            app.Label_4.Visible = 'off';
            app.Label_5 = uilabel(app.TablePanel);
            app.Label_5.Visible = 'off';
            app.Label_6 = uilabel(app.TablePanel);
            app.Label_6.Visible = 'off';
            app.Label_7 = uilabel(app.TablePanel);
            app.Label_7.Visible = 'off';
            app.YourHandPanel = uipanel(app.PokerGameUIFigure);
            app.YourHandPanel.Title = 'Your Hand';
            app.Image_9 = uiimage(app.YourHandPanel);
            app.Image_16 = uiimage(app.YourHandPanel);
            app.Label_8 = uilabel(app.YourHandPanel);
            app.Label_8.Visible = 'off';
            app.Label_9 = uilabel(app.YourHandPanel);
            app.Label_9.Visible = 'off';
            app.StartGameButton = uibutton(app.PokerGameUIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.AddtoAIHandButton = uibutton(app.PokerGameUIFigure, 'push');
            app.AddtoAIHandButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoAIHandButtonPushed, true);
            app.AddtoAIHandButton.Text = 'Add to AI Hand';
            app.AddtoTableButton = uibutton(app.PokerGameUIFigure, 'push');
            app.AddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableButtonPushed, true);
            app.AddtoTableButton.Text = 'Add to Table';
            app.AddtoYourHandButton = uibutton(app.PokerGameUIFigure, 'push');
            app.AddtoYourHandButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoYourHandButtonPushed, true);
            app.AddtoYourHandButton.Text = 'Add to Your Hand';
            app.SeewhowonButton = uibutton(app.PokerGameUIFigure, 'push');
            app.SeewhowonButton.ButtonPushedFcn = createCallbackFcn(app, @SeewhowonButtonPushed, true);
            app.SeewhowonButton.Text = 'See who won';
            app.WinnerLabel = uilabel(app.PokerGameUIFigure);
            app.WinnerLabel.Text = 'Winner: ';
            app.NewHandButton = uibutton(app.PokerGameUIFigure, 'push');
            app.NewHandButton.ButtonPushedFcn = createCallbackFcn(app, @NewHandButtonPushed, true);
            app.NewHandButton.Text = 'New Hand';
            app.YourBestHandLabel = uilabel(app.PokerGameUIFigure);
            app.YourBestHandLabel.Text = 'Your Best Hand: ';
            app.AIsBestHandLabel = uilabel(app.PokerGameUIFigure);
            app.AIsBestHandLabel.Text = 'AI''s Best Hand: ';
            app.AIsbankLabel = uilabel(app.PokerGameUIFigure);
            app.AIsbankLabel.Text = 'AI''s bank: ';
            app.YourbankLabel = uilabel(app.PokerGameUIFigure);
            app.YourbankLabel.Text = 'Your bank: ';
            app.YourbetLabel = uilabel(app.PokerGameUIFigure);
            app.YourbetLabel.Text = 'Your bet: ';
            app.AIsbetLabel = uilabel(app.PokerGameUIFigure);
            app.AIsbetLabel.Text = 'AI''s bet: ';
            % Show the figure after all components are created
            app.PokerGameUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PokerGameUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PokerGameUIFigure)
        end
    end
end